classdef PlastID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        ProcessarpastaButton        matlab.ui.control.Button
        PretratamentoDropDown       matlab.ui.control.DropDown
        PretratamentoDropDownLabel  matlab.ui.control.Label
        FluorDropDown               matlab.ui.control.DropDown
        FluorDropDownLabel          matlab.ui.control.Label
        ProcessarimagemButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessarimagemButton
        function ProcessarimagemButtonPushed(app, event)
% Criar estrutura do ficheiro opcao

opcao.avanco  = 'imagem';

opcao.fluor   = app.FluorDropDown.Value;

opcao.pretrat = app.PretratamentoDropDown.Value;


%% conversão pixel -> unidade real

opcao.dist = 1;

%% chamar processamento

adquirir(opcao);
        end

        % Button pushed function: ProcessarpastaButton
        function ProcessarpastaButtonPushed(app, event)
            % Criar estrutura opcao

            opcao.avanco = 'series';

            opcao.fluor = app.FluorDropDown.Value;

            opcao.pretrat = app.PretratamentoDropDown.Value;

            opcao.dist = 1; % valor por defeito; sobrescrito pelo adquirir.m se TIFF tiver escala

            % Escolher pasta

            opcao.direct = uigetdir( ...
                pwd,...
                'Escolha a pasta com as imagens');

            if opcao.direct == 0
                return
            end

            % Processar

            processar_lote(opcao);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessarimagemButton = uibutton(app.UIFigure, 'push');
            app.ProcessarimagemButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarimagemButtonPushed, true);
            app.ProcessarimagemButton.Text = 'Processar imagem';
            app.FluorDropDownLabel = uilabel(app.UIFigure);
            app.FluorDropDownLabel.Text = 'Fluor';
            app.FluorDropDown = uidropdown(app.UIFigure);
            app.FluorDropDown.Items = {'red', 'green', 'org'};
            app.FluorDropDown.Value = 'red';
            app.PretratamentoDropDownLabel = uilabel(app.UIFigure);
            app.PretratamentoDropDownLabel.Text = 'Pre-tratamento';
            app.PretratamentoDropDown = uidropdown(app.UIFigure);
            app.PretratamentoDropDown.Items = {'sim', 'nao'};
            app.PretratamentoDropDown.Value = 'sim';
            app.ProcessarpastaButton = uibutton(app.UIFigure, 'push');
            app.ProcessarpastaButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarpastaButtonPushed, true);
            app.ProcessarpastaButton.Text = 'Processar pasta';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'generated-image2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlastID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end